classdef SignalAppV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        HumiLabel_2         matlab.ui.control.Label
        VolLabel_2          matlab.ui.control.Label
        Lamp                matlab.ui.control.Lamp
        Reset_2             matlab.ui.control.Button
        Label               matlab.ui.control.Label
        VLabel              matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        LabelStopButton     matlab.ui.control.Button
        HumidityGauge       matlab.ui.control.Gauge
        HumidityGaugeLabel  matlab.ui.control.Label
        HumidityLabel       matlab.ui.control.Label
        VoltageVLabel       matlab.ui.control.Label
        HumiLabel           matlab.ui.control.Label
        VolLabel            matlab.ui.control.Label
        Reset               matlab.ui.control.Button
        Start               matlab.ui.control.Button
        HumidityAxes        matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s
        stop
        tableData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM4",9600,"Timeout",4);

            app.stop=1;

            app.UIAxes.YLim = [0 3.5];
            app.HumidityAxes.YLim = [0 100];
        end

        % Button pushed function: Start
        function StartPushed(app, event)
            column = 1;
            app.stop = 1;
            startTime = datetime('now');

            AirValue = 3300;
            WaterValue = 0;


            while app.stop
                v = str2double(readline(app.s));
                t =  datetime('now') - startTime; 
                
                humiditypercent = mapfun(v,AirValue,WaterValue,0,100);

                vol(column) = v(1)/1000;
                humidity(column) = mapfun(v(1),AirValue,WaterValue,0,100);
                time(column) = t;
                
                Voltage = (vol)';
                Col = (time)';
                Humi = (humidity)';
                app.tableData = table(Col,Humi,Voltage,'VariableNames', {'x','y','z'});
                app.UITable.Data = app.tableData;
                column = column + 1;

                plot(app.UIAxes,app.tableData.x,app.tableData.z)
                app.UIAxes.XLim = [t-seconds(5) t];


                app.VolLabel.Text = char(string(v/1000)); %label set

                if (humiditypercent >= 100)
                    app.HumidityGauge.Value = 100; %Gauge set
                    app.HumiLabel.Text = '100'; %Humi label set
                    plot(app.HumidityAxes,app.tableData.x,app.tableData.y)
                    app.HumidityAxes.XLim = [t-seconds(5) t];
                    
                elseif (humiditypercent <=0)
                    app.HumidityGauge.Value = 0;
                    app.HumiLabel.Text = '0'; %Humi label set
                    plot(app.HumidityAxes,app.tableData.x,app.tableData.y)
                    app.HumidityAxes.XLim = [t-seconds(5) t];
                    
                elseif(humiditypercent >0 && humiditypercent < 100)
                    app.HumidityGauge.Value = humiditypercent;
                    app.HumiLabel.Text = char(string(humiditypercent)); %Humi label set
                    plot(app.HumidityAxes,app.tableData.x,app.tableData.y)
                    app.HumidityAxes.XLim = [t-seconds(5) t];
                end


            end
        end

        % Button pushed function: Reset
        function LanStartButtonPushed(app, event)
            column = 1;
            app.stop = 1;
            while app.stop
            v = str2double(readline(app.s));
            
            %m = integer(map(v, 620, 310, 0, 100));
            
            %app.VolLabel.Text = char(v);
            app.VoltageGauge.Value = v;
            
            app.VolLabel.Text = char(string(v));

                vol(column) = v(1);
                time(column) = column;

                Voltage = (vol)';
                Col = (time)';
                data = table(Col,Voltage,'VariableNames', {'x','y'});
                app.UITable.Data = data;
                column = column + 1;


            end
        end

        % Button pushed function: LabelStopButton
        function LabelStopButtonPushed(app, event)
            app.stop = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voltage Moisture')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.HumidityAxes = uiaxes(app.UIFigure);
            title(app.HumidityAxes, 'Humidity Moisture')
            xlabel(app.HumidityAxes, 'Time (sec)')
            ylabel(app.HumidityAxes, 'Humidity (%)')
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartPushed, true);
            app.Start.Text = 'Graph Start';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @LanStartButtonPushed, true);
            app.Reset.Text = 'Start pump';
            app.VolLabel = uilabel(app.UIFigure);
            app.VolLabel.Text = 'Voltage';
            app.HumiLabel = uilabel(app.UIFigure);
            app.HumiLabel.Text = 'Humidity';
            app.VoltageVLabel = uilabel(app.UIFigure);
            app.VoltageVLabel.Text = 'Voltage (V)    :';
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity (%) :';
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'HumidityGauge';
            app.HumidityGauge = uigauge(app.UIFigure, 'circular');
            app.LabelStopButton = uibutton(app.UIFigure, 'push');
            app.LabelStopButton.ButtonPushedFcn = createCallbackFcn(app, @LabelStopButtonPushed, true);
            app.LabelStopButton.Text = 'Graph Stop';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time(s)'; 'Humidity(%)'; 'Voltage(%)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Reset_2 = uibutton(app.UIFigure, 'push');
            app.Reset_2.Text = 'Stop pump';
            app.Lamp = uilamp(app.UIFigure);
            app.VolLabel_2 = uilabel(app.UIFigure);
            app.VolLabel_2.Text = 'Pump status :';
            app.HumiLabel_2 = uilabel(app.UIFigure);
            app.HumiLabel_2.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAppV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end