classdef SignalAppV5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ExporttoExcelButton  matlab.ui.control.Button
        PumpLabel            matlab.ui.control.Label
        PumpStatusLabel      matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        Label                matlab.ui.control.Label
        VLabel               matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        StopButton           matlab.ui.control.Button
        HumidityGauge        matlab.ui.control.Gauge
        HumidityGaugeLabel   matlab.ui.control.Label
        HumidityLabel        matlab.ui.control.Label
        VoltageVLabel        matlab.ui.control.Label
        HumiLabel            matlab.ui.control.Label
        VolLabel             matlab.ui.control.Label
        Start                matlab.ui.control.Button
        HumidityAxes         matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        esp
        stop
        tableData
        a
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.esp = serialport("COM5",9600,"Timeout",4);

            app.stop=1;
            
            app.UIAxes.YLim = [0 3.5];
            app.HumidityAxes.YLim = [0 100];

            AirValue = 3300;
            WaterValue = 0;

            while 1
                v = str2double(readline(app.esp));
                humiditypercent = mapfun(v,AirValue,WaterValue,0,100);
                voltagechange =v/1000;

                 if (humiditypercent >= 100)
                    app.HumidityGauge.Value = 100; %Gauge set
                    app.HumiLabel.Text = '100'; %Humi label set
                    app.VolLabel.Text = char(string(voltagechange)); %label set
                    
                elseif (humiditypercent <=0)
                    app.HumidityGauge.Value = 0;
                    app.HumiLabel.Text = '0'; %Humi label set
                    app.VolLabel.Text = char(string(voltagechange)); %label set
                    
                elseif(humiditypercent >0 && humiditypercent < 100)
                    app.HumidityGauge.Value = humiditypercent;
                    app.HumiLabel.Text = char(string(humiditypercent)); %Humi label set
                    app.VolLabel.Text = char(string(voltagechange)); %label set
                end

                %pump off on
                if (humiditypercent < 40)
                    app.PumpLabel.Text = 'ON';
                    app.Lamp.Color = 'green';
                elseif (humiditypercent >50)
                    app.Lamp.Color = 'red';
                    app.PumpLabel.Text = 'OFF';
                end
            end
        end

        % Button pushed function: Start
        function StartPushed(app, event)
            global setdata
            column = 1;
            app.stop = 1;
            startTime = datetime('now');

            AirValue = 3300;
            WaterValue = 0;


            while app.stop
                v = str2double(readline(app.esp));
                t =  datetime('now') - startTime; 
                
                humiditypercent = mapfun(v,AirValue,WaterValue,0,100);
                voltagechange =v/1000;

                vol(column) = v(1)/1000;
                humidity(column) = mapfun(v(1),AirValue,WaterValue,0,100);
                time(column) = t;
                
                Voltage = (vol)';
                Col = (time)';
                Humi = (humidity)';
                setdata = [Col,Humi,Voltage];
                app.tableData = table(Col,Humi,Voltage,'VariableNames', {'x','y','z'});
                app.UITable.Data = app.tableData;
                column = column + 1;

                plot(app.UIAxes,app.tableData.x,app.tableData.z)
                app.UIAxes.XLim = [t-seconds(5) t];

                if (humiditypercent >= 100)
                    app.HumidityGauge.Value = 100; %Gauge set
                    app.HumiLabel.Text = '100'; %Humi label set
                    plot(app.HumidityAxes,app.tableData.x,app.tableData.y)
                    app.HumidityAxes.XLim = [t-seconds(5) t];
                    app.VolLabel.Text = char(string(voltagechange)); %label set
                    
                elseif (humiditypercent <=0)
                    app.HumidityGauge.Value = 0;
                    app.HumiLabel.Text = '0'; %Humi label set
                    plot(app.HumidityAxes,app.tableData.x,app.tableData.y)
                    app.HumidityAxes.XLim = [t-seconds(5) t];
                    app.VolLabel.Text = char(string(voltagechange)); %label set
                    
                elseif(humiditypercent >0 && humiditypercent < 100)
                    app.HumidityGauge.Value = humiditypercent;
                    app.HumiLabel.Text = char(string(humiditypercent)); %Humi label set
                    plot(app.HumidityAxes,app.tableData.x,app.tableData.y)
                    app.HumidityAxes.XLim = [t-seconds(5) t];
                    app.VolLabel.Text = char(string(voltagechange)); %label set
                end

                %pump off on
                if (humiditypercent < 40)

                    app.PumpLabel.Text = 'ON';
                    app.Lamp.Color = 'green';
                elseif (humiditypercent >50)
                    app.Lamp.Color = 'red';
                    app.PumpLabel.Text = 'OFF';
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = 0;
        end

        % Button pushed function: ExporttoExcelButton
        function ExporttoExcelButtonPushed(app, event)
            filename = 'HumidityData.xlsx';
            writetable(app.tableData,filename)
            winopen 'HumidityData.xlsx';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voltage Moisture')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.HumidityAxes = uiaxes(app.UIFigure);
            title(app.HumidityAxes, 'Humidity Moisture')
            xlabel(app.HumidityAxes, 'Time (sec)')
            ylabel(app.HumidityAxes, 'Humidity (%)')
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartPushed, true);
            app.Start.Text = 'Graph Start';
            app.VolLabel = uilabel(app.UIFigure);
            app.VolLabel.Text = 'Voltage';
            app.HumiLabel = uilabel(app.UIFigure);
            app.HumiLabel.Text = 'Humidity';
            app.VoltageVLabel = uilabel(app.UIFigure);
            app.VoltageVLabel.Text = 'Voltage (V)    :';
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity (%) :';
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'HumidityGauge';
            app.HumidityGauge = uigauge(app.UIFigure, 'circular');
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Graph Stop';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time(s)'; 'Humidity(%)'; 'Voltage(%)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Lamp = uilamp(app.UIFigure);
            app.PumpStatusLabel = uilabel(app.UIFigure);
            app.PumpStatusLabel.Text = 'Pump status :';
            app.PumpLabel = uilabel(app.UIFigure);
            app.PumpLabel.Text = 'Status';
            app.ExporttoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelButton.Text = 'Export to Excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAppV5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end