classdef pam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button2      matlab.ui.control.Button
        Label        matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        Gauge        matlab.ui.control.Gauge
        GaugeLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        a % arduino instance
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = arduino("COM5", "ESP32-WROOM-DevKitC");
            configurePin(app.a, "D34", "AnalogInput");

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            clear app.a;
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            value = readVoltage(app.a, "D34");
            app.Gauge.Value = value;
            app.Label.Text = char(string(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end