classdef demo_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        PlotButton    matlab.ui.control.Button
        Lamp_2        matlab.ui.control.Lamp
        Lamp          matlab.ui.control.Lamp
        UITable       matlab.ui.control.Table
        startButton   matlab.ui.control.Button
        VoltageLabel  matlab.ui.control.Label
        StopButton    matlab.ui.control.Button
        Gauge         matlab.ui.control.Gauge
        GaugeLabel    matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a % arduino instance
        b % for start/stop button
        tableData
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = arduino("COM5", "ESP32-WROOM-DevKitC");
            app.b = 1;
            configurePin(app.a, "D34", "AnalogInput");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.b = 0;
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.b = 1;
            column = 1;
            while app.b
                value = readVoltage(app.a, "D34");
                app.Gauge.Value = value;
                app.VoltageLabel.Text = char(string(value));

                vol(column) = value(1);
                time(column) = column;
                Voltage = (vol)';
                Col = (time)';
                app.tableData = table(Voltage,Col,'VariableNames', {'x','y'});
                app.UITable.Data = app.tableData;
                column = column + 1;
                app.UIAxes.YLim = [0 3.3];
                plot(app.UIAxes,app.tableData.y,app.tableData.x)
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.UIAxes.YLim = [0 3.3];
            plot(app.UIAxes,app.tableData.y,app.tableData.x)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'soil moisture')
            xlabel(app.UIAxes, 'value')
            ylabel(app.UIAxes, 'time')
            zlabel(app.UIAxes, 'Z')
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [0 4];
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VoltageLabel = uilabel(app.UIFigure);
            app.VoltageLabel.Text = 'Voltage';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Voltage'; 'Time'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end